classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxesTop                matlab.ui.control.UIAxes
        ConfigDropDownLabel      matlab.ui.control.Label
        ConfigDropDown           matlab.ui.control.DropDown
        RunButton                matlab.ui.control.Button
        UIParamTable             matlab.ui.control.Table
        Param1Slider             matlab.ui.control.Slider
        Param1Spinner            matlab.ui.control.Spinner
        UIAxesBottom             matlab.ui.control.UIAxes
        VariablesListBox_2Label  matlab.ui.control.Label
        VariablesListBoxBottom   matlab.ui.control.ListBox
        Param1DropDownLabel      matlab.ui.control.Label
        Param1DropDown           matlab.ui.control.DropDown
        VariablesListBoxLabel    matlab.ui.control.Label
        VariablesListBoxTop      matlab.ui.control.ListBox
        Param2Slider             matlab.ui.control.Slider
        Param2Spinner            matlab.ui.control.Spinner
        Param2DropDownLabel      matlab.ui.control.Label
        Param2DropDown           matlab.ui.control.DropDown
        Param3Slider             matlab.ui.control.Slider
        Param3Spinner            matlab.ui.control.Spinner
        Param3DropDownLabel      matlab.ui.control.Label
        Param3DropDown           matlab.ui.control.DropDown
        AutorunCheckBox          matlab.ui.control.CheckBox
        AnimationButton          matlab.ui.control.Button
        CustomPlotButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        physics_p;
        control_p;
        traj_p;
        sim_p;
        plot_p;
        param_values;
        q; % state position
        qdot; % state velocities
        qd; % desired state
        u; % input
        s; % other variables (usually sliding variables)
        t; % time
    end
    
    methods (Access = private)
        
        function table_data = loadTableParameters(app, param_struct, name)
            all_values = struct2cell(param_struct);
            all_names = fieldnames(param_struct);
            
            idx = find(cellfun(@(x) (isnumeric(x) && isscalar(x)),all_values));        
            
            table_struct.types = repmat({name}, [length(idx) 1]);
            table_struct.names = all_names(idx);
            table_struct.values = cell2mat(all_values(idx));
            
            table_data = struct2table(table_struct);
        end
        
        function plotValuesAxis(app, UIAxis, legend_names)
            
            values = zeros(length(app.t),length(legend_names));
                        
            for i=1:length(legend_names)
                
                uline_pos = strfind(legend_names{i},'_');
                
                prefix = legend_names{i}(1:uline_pos-1);
                idx = str2double(legend_names{i}(uline_pos+1:end));
                
                values(:,i) = app.(prefix)(:,idx);                

            end
            
            plot(UIAxis,app.t,values);
            legend(UIAxis,legend_names);
        end
        
        function plotValues(app)
            app.plotValuesAxis(app.UIAxesTop, app.VariablesListBoxTop.Value);
            app.plotValuesAxis(app.UIAxesBottom, app.VariablesListBoxBottom.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startup();
            config_dir = dir('config');
            idx = endsWith({config_dir.name}, 'config.m')==1;
            config_list = {config_dir(idx).name};
            app.ConfigDropDown.Items = config_list;
            app.ConfigDropDownValueChanged();
            app.UIParamTable.ColumnEditable = [false, false, true];
            app.UIParamTable.RowName = {};
%             app.param_values = table2array(app.UIParamTable.DisplayData(:,3));
        end

        % Value changed function: ConfigDropDown
        function ConfigDropDownValueChanged(app, event)
            value = app.ConfigDropDown.Value;
            config_fun = str2func(value(1:end-2)); % remove *.m
            [app.physics_p, app.control_p, app.traj_p, app.sim_p, app.plot_p] = config_fun();

            table = [];
            table = [table; app.loadTableParameters(app.physics_p, 'Physics')];
            table = [table; app.loadTableParameters(app.control_p, 'Control')];
            table = [table; app.loadTableParameters(app.traj_p, 'Trajectory')];
            table = [table; app.loadTableParameters(app.sim_p, 'Simulation')];
            table = [table; app.loadTableParameters(app.plot_p, 'Plot')];
    
            q0 = app.sim_p.x0(1:end/2);
            qdot0 = app.sim_p.x0(end/2+1:end);            
                        
            qd0 = app.traj_p.sample_fun(0);
            
            [u0, s0] = app.control_p.control_fun(q0, qdot0, qd0, app.physics_p, app.control_p);
            
            q_size = length(q0);
            u_size = length(u0);
            s_size = length(s0);
            qd_size = length(qd0);
            
            q_list = mat2cell(cat(2, repmat('q_', q_size, 1), num2str((1:q_size)')), ones(q_size,1));
            qdot_list  = mat2cell(cat(2, repmat('qdot_', q_size, 1), num2str((1:q_size)')), ones(q_size,1));
            qd_list = mat2cell(cat(2, repmat('qd_', qd_size, 1), num2str((1:qd_size)')), ones(qd_size,1));
            u_list = mat2cell(cat(2, repmat('u_', u_size, 1), num2str((1:u_size)')), ones(u_size,1));
            s_list = mat2cell(cat(2, repmat('s_', s_size, 1), num2str((1:s_size)')), ones(s_size,1));
            
            app.VariablesListBoxTop.Items = {};
            app.VariablesListBoxTop.Items(end+1:end+q_size) = q_list;
            app.VariablesListBoxTop.Items(end+1:end+q_size) = qdot_list;
            app.VariablesListBoxTop.Items(end+1:end+qd_size) = qd_list;
            app.VariablesListBoxTop.Items(end+1:end+u_size) = u_list;
            app.VariablesListBoxTop.Items(end+1:end+s_size) = s_list;
            
            app.VariablesListBoxBottom.Items = app.VariablesListBoxTop.Items;

            app.UIParamTable.Data = table;
            
            app.VariablesListBoxTop.Value = q_list;
            app.VariablesListBoxBottom.Value = u_list;

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            [app.t,x] = ode45(@(t, x) ode_fun(t, x, app.physics_p, app.control_p, app.traj_p, app.sim_p), app.sim_p.t, app.sim_p.x0);
            
            % State remapping
            app.q = x(:,1:end/2);
            app.qdot = x(:,end/2+1:end);
            
            % Compute desired trajectory
            app.qd = app.traj_p.sample_fun(app.t);
            
            app.u = [];
            app.s = [];
            
            % TODO: define u for all timespecs at once
            for i=1:length(app.t)
                % FIXME: state variables are temporarily transposed for nested3d_control
                [app.u(i,:), app.s(i,:)] = app.control_p.control_fun(app.q(i,:)',app.qdot(i,:)',app.qd(i,:)',app.physics_p,app.control_p);
            end
            
            app.plotValues();

        end

        % Cell edit callback: UIParamTable
        function UIParamTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
                      
            newDisplayData = app.UIParamTable.DisplayData;
            
            i = indices(1);
            type = char(table2cell(newDisplayData(i,1)));
            field = char(table2cell(newDisplayData(i,2)));
            value = newData;
            
            if strcmp(type, 'Control')
                app.control_p.(field) = value;
            elseif strcmp(type, 'Physics')
                app.physics_p.(field) = value;
            elseif strcmp(type, 'Trajectory')
                app.traj_p.(field) = value;
            elseif strcmp(type, 'Simulation')
                app.sim_p.(field) = value;
            elseif strcmp(type, 'Plot')
                app.plot_p.(field) = value;
            end
            
            if app.AutorunCheckBox.Value
                app.RunButtonPushed()
            end
                
%             
%             app.UIParamTable.ColumnEditable
%             app.UIParamTable.Enable
        end

        % Value changed function: VariablesListBoxBottom
        function VariablesListBoxBottomValueChanged(app, event)
            app.plotValuesAxis(app.UIAxesBottom, app.VariablesListBoxBottom.Value);
        end

        % Value changed function: VariablesListBoxTop
        function VariablesListBoxTopValueChanged(app, event)
            app.plotValuesAxis(app.UIAxesTop, app.VariablesListBoxTop.Value);   
        end

        % Button pushed function: AnimationButton
        function AnimationButtonPushed(app, event)
            app.plot_p.plot_animation(app.t, app.q, app.qd, app.physics_p);
        end

        % Button pushed function: CustomPlotButton
        function CustomPlotButtonPushed(app, event)
            app.plot_p.plot_state(app.t, app.q, app.qdot, app.qd, app.u, app.physics_p, app.control_p);
        end

        % Cell selection callback: UIParamTable
        function UIParamTableCellSelection(app, event)
%             indices = event.Indices;
            event
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesTop = uiaxes(app.UIFigure);
            title(app.UIAxesTop, '', 'Interpreter', 'latex')
            xlabel(app.UIAxesTop, '', 'Interpreter', 'latex')
            ylabel(app.UIAxesTop, 'Y', 'Interpreter', 'latex')
            app.ConfigDropDownLabel = uilabel(app.UIFigure);
            app.ConfigDropDownLabel.Text = 'Config';
            app.ConfigDropDown = uidropdown(app.UIFigure);
            app.ConfigDropDown.Items = {};
            app.ConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigDropDownValueChanged, true);
            app.ConfigDropDown.Value = {};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UIParamTable = uitable(app.UIFigure);
            app.UIParamTable.ColumnName = {'Type'; 'Name'; 'Value'};
            app.UIParamTable.RowName = {''};
            app.UIParamTable.ColumnSortable = [true true false];
            app.UIParamTable.ColumnEditable = [false false true];
            app.UIParamTable.CellEditCallback = createCallbackFcn(app, @UIParamTableCellEdit, true);
            app.UIParamTable.CellSelectionCallback = createCallbackFcn(app, @UIParamTableCellSelection, true);
            app.Param1Slider = uislider(app.UIFigure);
            app.Param1Slider.Limits = [-100 100];
            app.Param1Spinner = uispinner(app.UIFigure);
            app.UIAxesBottom = uiaxes(app.UIFigure);
            title(app.UIAxesBottom, '', 'Interpreter', 'latex')
            xlabel(app.UIAxesBottom, 'Time [seconds]', 'Interpreter', 'latex')
            ylabel(app.UIAxesBottom, 'Y', 'Interpreter', 'latex')
            app.VariablesListBox_2Label = uilabel(app.UIFigure);
            app.VariablesListBox_2Label.Text = 'Variables';
            app.VariablesListBoxBottom = uilistbox(app.UIFigure);
            app.VariablesListBoxBottom.Multiselect = 'on';
            app.VariablesListBoxBottom.ValueChangedFcn = createCallbackFcn(app, @VariablesListBoxBottomValueChanged, true);
            app.VariablesListBoxBottom.Value = {'Item 1'};
            app.Param1DropDownLabel = uilabel(app.UIFigure);
            app.Param1DropDownLabel.Text = 'Param 1';
            app.Param1DropDown = uidropdown(app.UIFigure);
            app.VariablesListBoxLabel = uilabel(app.UIFigure);
            app.VariablesListBoxLabel.Text = 'Variables';
            app.VariablesListBoxTop = uilistbox(app.UIFigure);
            app.VariablesListBoxTop.Multiselect = 'on';
            app.VariablesListBoxTop.ValueChangedFcn = createCallbackFcn(app, @VariablesListBoxTopValueChanged, true);
            app.VariablesListBoxTop.Value = {'Item 3'};
            app.Param2Slider = uislider(app.UIFigure);
            app.Param2Slider.Limits = [-100 100];
            app.Param2Spinner = uispinner(app.UIFigure);
            app.Param2DropDownLabel = uilabel(app.UIFigure);
            app.Param2DropDownLabel.Text = 'Param 2';
            app.Param2DropDown = uidropdown(app.UIFigure);
            app.Param3Slider = uislider(app.UIFigure);
            app.Param3Slider.Limits = [-100 100];
            app.Param3Spinner = uispinner(app.UIFigure);
            app.Param3DropDownLabel = uilabel(app.UIFigure);
            app.Param3DropDownLabel.Text = 'Param 3';
            app.Param3DropDown = uidropdown(app.UIFigure);
            app.AutorunCheckBox = uicheckbox(app.UIFigure);
            app.AutorunCheckBox.Text = 'Autorun';
            app.AutorunCheckBox.Value = true;
            app.AnimationButton = uibutton(app.UIFigure, 'push');
            app.AnimationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimationButtonPushed, true);
            app.AnimationButton.Text = 'Animation';
            app.CustomPlotButton = uibutton(app.UIFigure, 'push');
            app.CustomPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CustomPlotButtonPushed, true);
            app.CustomPlotButton.Text = 'Custom Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end